classdef DB_export < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UITable              matlab.ui.control.Table
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        ExporttoExcelButton  matlab.ui.control.Button
        Label                matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path)
            imshow('cstb_logo.png','parent',app.UIAxes2) ;
            title(app.UIAxes2,[]);
            xlabel(app.UIAxes2,[]) ;
            ylabel(app.UIAxes2,[]);
            app.UIAxes2.XAxis.TickLabels={} ;
            app.UIAxes2.YAxis.TickLabels={} ;
            app.Lamp.Color='red'; 
            path_end=strcat(path,'\*.*') ;
            value_dir=dir(path_end) ;
%             file_name=value_dir(1).name ; 
            for i=1:size(value_dir)
                a=string(value_dir(i).name) ;
                M(i)=a ;
            end
            M1=transpose(M) ; 
            M2=char(M1) ; 
            for i=1:size(value_dir)
                T(i)={M2(i,:)} ; 
            end
            T2=transpose(T);
            app.UITable.Data=T2; 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File '; 'Element'; 'Match'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spectre')
            xlabel(app.UIAxes, 'Fréquence')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ExporttoExcelButton = uibutton(app.UIFigure, 'push');
            app.ExporttoExcelButton.Text = 'Export to Excel';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = DB_export(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end