classdef Dialog_box_plot_table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSTBDetailsUIFigure     matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        ByIbrahimELGHARBILabel  matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        UIAxes2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Description
        input_x; 
        input_y ;
        index_sort_data ; 
        nom_elements_ref1={'Actinotite','Amosite','Antophilite','Chrysotile','Chrysotile_HLS_Canada','Chrysotile_Spi_Canada','Chrysotile_Spi_Rhodésie','Chrysotile_Spi_Zimbabwe','Crocidolite','tremolite'} ;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, n_input, t_input, Corr_input)

            imshow('cstb_logo.png','parent',app.UIAxes2) ;
            title(app.UIAxes2,[]);
            xlabel(app.UIAxes2,[]) ;
            ylabel(app.UIAxes2,[]);
            app.UIAxes2.XAxis.TickLabels={} ;
            app.UIAxes2.YAxis.TickLabels={} ;
            %             Table UI Updated with value from Correlation_matrix
            % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
            plot(app.UIAxes,n_input,t_input,'b') ;
            app.input_x=n_input ;
            app.input_y=t_input; 
            [Corr_input_sorted,index]=sort(Corr_input(:),'descend') ;
            Corr_input_sorted=Corr_input_sorted*100 ; 

            %             data_table={cell2mat(app.nom_elements_ref1(index(10))),Corr_input_sorted(10);cell2mat(app.nom_elements_ref1(index(9))),Corr_input_sorted(9);cell2mat(app.nom_elements_ref1(index(8))),Corr_input_sorted(8);cell2mat(app.nom_elements_ref1(index(7))),Corr_input_sorted(7);cell2mat(app.nom_elements_ref1(index(6))),Corr_input_sorted(6);cell2mat(app.nom_elements_ref1(index(5))),Corr_input_sorted(5);cell2mat(app.nom_elements_ref1(index(4))),Corr_input_sorted(4);cell2mat(app.nom_elements_ref1(index(3))),Corr_input_sorted(3);cell2mat(app.nom_elements_ref1(index(2))),Corr_input_sorted(2);cell2mat(app.nom_elements_ref1(index(1))),Corr_input_sorted(1)};
            
            app.index_sort_data=index ; 
            app.UITable.Data={cell2mat(app.nom_elements_ref1(index(1))),Corr_input_sorted(1);cell2mat(app.nom_elements_ref1(index(2))),Corr_input_sorted(2);cell2mat(app.nom_elements_ref1(index(3))),Corr_input_sorted(3);cell2mat(app.nom_elements_ref1(index(4))),Corr_input_sorted(4);cell2mat(app.nom_elements_ref1(index(5))),Corr_input_sorted(5);cell2mat(app.nom_elements_ref1(index(6))),Corr_input_sorted(6);cell2mat(app.nom_elements_ref1(index(7))),Corr_input_sorted(7);cell2mat(app.nom_elements_ref1(index(8))),Corr_input_sorted(8);cell2mat(app.nom_elements_ref1(index(9))),Corr_input_sorted(9);cell2mat(app.nom_elements_ref1(index(10))),Corr_input_sorted(10)};

            %             app.UITable.Data={cell2mat(app.nom_elements_ref1(1)),Corr_input(1);cell2mat(app.nom_elements_ref1(2)),Corr_input_sorted(2);cell2mat(app.nom_elements_ref1(index(8))),Corr_input_sorted(8);cell2mat(app.nom_elements_ref1(index(7))),Corr_input_sorted(7);cell2mat(app.nom_elements_ref1(index(6))),Corr_input_sorted(6);cell2mat(app.nom_elements_ref1(index(5))),Corr_input_sorted(5);cell2mat(app.nom_elements_ref1(index(4))),Corr_input_sorted(4);cell2mat(app.nom_elements_ref1(index(3))),Corr_input_sorted(3);cell2mat(app.nom_elements_ref1(index(2))),Corr_input_sorted(2);cell2mat(app.nom_elements_ref1(index(1))),Corr_input_sorted(1)};

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            id=event.Indices(1) ;
            % % % % % % % % % % % % % % % % % % % % % % % % %
            path_corr='C:\Users\LEILA\Desktop\STAGE CSTB\BD SPECTRE 2\Spectre BD sans nom pur' ;
            
            id1=app.index_sort_data(id) ;
            chr=int2str(id1) ;
            
            chr_file=strcat(chr,'.txt') ;
            
            fid = fopen(fullfile(path_corr,chr_file),'rt');
            A = textscan(fid, '%f %f', 'HeaderLines',2);
            fclose(fid);
            n=A{1}  ;
            y=A{2} ;
            
            
            [z,a,it] = backcor(n,y,10,2*rms(y),'sh');
            t=y-z ;
            t=imgaussfilt(y-z) ;
            t_golay=sgolayfilt(t,3,11) ;
            t_golay=normalize(t_golay) ;
            for i=1:size(n)
                if t_golay(i,1)<=2*rms(t_golay)
                    t_golay(i,1)=0 ;
                end
            end
% % % % % % %             Comme les spectres sont inversés donc je fais cela
            t_golay_transpose=transpose(t_golay);
            t_golay_transpose=fliplr(t_golay_transpose) ;
            t_golay=transpose(t_golay_transpose) ;
            n_transpose=transpose(n);
            n_transpose=fliplr(n_transpose) ;
            n=transpose(n_transpose) ;
% % % % % % % % % % % % % % % % % % % % % % % % % % % %             
            plot(app.UIAxes,app.input_x,app.input_y,'b',n,t_golay,'r') ;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSTBDetailsUIFigure = uifigure;
            app.CSTBDetailsUIFigure.Name = 'CSTB Details';
            app.UIAxes = uiaxes(app.CSTBDetailsUIFigure);
            title(app.UIAxes, 'Spectre')
            xlabel(app.UIAxes, 'Fréquence')
            ylabel(app.UIAxes, 'Amplitude')
            app.ByIbrahimELGHARBILabel = uilabel(app.CSTBDetailsUIFigure);
            app.ByIbrahimELGHARBILabel.Text = 'By Ibrahim EL GHARBI ';
            app.UITable = uitable(app.CSTBDetailsUIFigure);
            app.UITable.ColumnName = {'Element'; 'Pourcentage'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes2 = uiaxes(app.CSTBDetailsUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dialog_box_plot_table(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CSTBDetailsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSTBDetailsUIFigure)
        end
    end
end