classdef View_original < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ViewOriginalUIFigure  matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        RemoveBaselineButton  matlab.ui.control.Button
        RemoveNoiseButton     matlab.ui.control.Button
        OriginalButton        matlab.ui.control.Button
    end


    properties (Access = public)
        n ; 
        y; 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, n_original, y_original)
            plot(app.UIAxes,n_original,y_original,'b') ;
            app.n=n_original ;
            app.y=y_original ;
        end

        % Button pushed function: RemoveBaselineButton
        function RemoveBaselineButtonPushed(app, event)
            
            sigx = 1;
            threashold=2*rms(app.y) ;
            [z,a,it] = backcor(app.n,app.y,10,threashold,'sh');
            %               background = z / max(z) * sigx;
            t=app.y-z ;
            t=imgaussfilt(app.y-z) ;
            t_golay=sgolayfilt(t,3,11) ;
            t_golay=normalize(t_golay) ;
            plot(app.UIAxes,app.n,t_golay,'b') ;
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
             sigx = 1;
            threashold=2*rms(app.y) ;
            [z,a,it] = backcor(app.n,app.y,10,threashold,'sh');
            %               background = z / max(z) * sigx;
            t=app.y-z ;
            t=imgaussfilt(app.y-z) ;
            t_golay=sgolayfilt(t,3,11) ;
            t_golay=normalize(t_golay) ;
            for i=1:size(app.n)
                if t_golay(i,1)<=2*rms(t_golay)
                    t_golay(i,1)=0 ;
                end
            end
            
            plot(app.UIAxes,app.n,t_golay,'b') ;
        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
            plot(app.UIAxes,app.n,app.y,'b') ;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ViewOriginalUIFigure = uifigure;
            app.ViewOriginalUIFigure.Name = 'View Original';
            app.UIAxes = uiaxes(app.ViewOriginalUIFigure);
            title(app.UIAxes, 'Spectre')
            xlabel(app.UIAxes, 'Fréquence')
            ylabel(app.UIAxes, 'Amplitude')
            app.RemoveBaselineButton = uibutton(app.ViewOriginalUIFigure, 'push');
            app.RemoveBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBaselineButtonPushed, true);
            app.RemoveBaselineButton.Text = 'Remove Baseline';
            app.RemoveNoiseButton = uibutton(app.ViewOriginalUIFigure, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.OriginalButton = uibutton(app.ViewOriginalUIFigure, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Text = 'Original';
        end
    end

    methods (Access = public)

        % Construct app
        function app = View_original(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ViewOriginalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ViewOriginalUIFigure)
        end
    end
end